classdef Plot_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        LeftPanel   matlab.ui.container.Panel
        Lamp        matlab.ui.control.Lamp
        PlotButton  matlab.ui.control.Button
        EditField   matlab.ui.control.NumericEditField
        Label_2     matlab.ui.control.Label
        DropDown    matlab.ui.control.DropDown
        Label       matlab.ui.control.Label
        RightPanel  matlab.ui.container.Panel
        UIAxes      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
s=serialport(app.DropDown.Value,115200);
flush(s);
configureTerminator(s,"CR");

InputBufferSize = 400;
margin = 100;
t = 1:InputBufferSize;
InputBuffer = zeros(1,InputBufferSize);

for i=1:4000
    while InputBuffer(1)==0
        for n = 2:InputBufferSize
            InputBuffer(n-1) = InputBuffer(n);
        end
        out = readline(s); 
        InputBuffer(InputBufferSize) = out;
    end

    t=t+1;

    for j = 2:InputBufferSize
        InputBuffer(j-1) = InputBuffer(j);
    end
    out = readline(s); 
    InputBuffer(InputBufferSize) = out;
    plot(app.UIAxes,t,InputBuffer(1,:),'-r');
    axis(app.UIAxes,[t(1) t(InputBufferSize) min(InputBuffer)-margin max(InputBuffer)+margin+10]);
    pause(0.1);

    [m,im]=min(InputBuffer(1:200));
    for k=im+10:InputBufferSize
        if(abs(InputBuffer(k)-m)<=50)
            imm=k;
            break;
        else
            imm=im;
        end
    end

    T=0.01*(imm-im);

    q=60/T;

    if(q>=120 || q<=40)
        app.Lamp.Color="1,0,0";
    else
        app.Lamp.Color="0,1,0";
    end

    app.EditField.Value=q;

    %print(T);
end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {165, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '串口';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11'};
            app.DropDown.Value = 'COM1';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '每分钟跳动次数';
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Lamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'ECG')
            xlabel(app.UIAxes, 'time(/0.01s)')
            ylabel(app.UIAxes, 'Voltage(mV)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plot_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end