classdef Approximation_Function < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        LagrangeInterpolatingPolynomialsTab  matlab.ui.container.Tab
        ApproxiationFunctionEditField  matlab.ui.control.EditField
        ApproxiationFunctionEditFieldLabel  matlab.ui.control.Label
        ReadfromExcelButton_4          matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        PlotButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        CubicSplineInterpolantTab      matlab.ui.container.Tab
        RightderivativeEditField       matlab.ui.control.NumericEditField
        RightderivativeEditFieldLabel  matlab.ui.control.Label
        LeftderivativeEditField        matlab.ui.control.NumericEditField
        LeftderivativeEditFieldLabel   matlab.ui.control.Label
        UITable2                       matlab.ui.control.Table
        ReadfromExcelButton_2          matlab.ui.control.Button
        UITable_2                      matlab.ui.control.Table
        PlotButton_4                   matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        DiscreteLeastSquaresApproximationTab  matlab.ui.container.Tab
        ApproxiationFunctionEditField_2  matlab.ui.control.EditField
        ApproxiationFunctionEditField_2Label  matlab.ui.control.Label
        OrderofFuntionEditField        matlab.ui.control.NumericEditField
        OrderofFuntionEditFieldLabel   matlab.ui.control.Label
        ReadfromExcelButton_3          matlab.ui.control.Button
        UITable_3                      matlab.ui.control.Table
        PlotButton_5                   matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xdata % 输入的x值
        ydata % 输入的y值
        point % Table的读取值
    end

    methods (Access = private)

        function funL=Lagrange(~, xx, yy) %拉格朗日内插法的算法代码
            syms x;
            n = length(xx);
            f=0;
            L=1;
            for i=1:n
                for j=1:n
                    if j~=i
                        L=L*(x-xx(j))/(xx(i)-xx(j));
                    end
                end
                f=f+L*yy(i);
                L=1;
            end
            funL=simplify(f);
        end

        function funDLS = Squares(~, xx, yy, n) %多项式近似的算法代码
            m=length(xx);
            if n>m
                funDLS=0;
            else
                sum=0;
                a=zeros(n+1, n+1);
                b=zeros(n+1,1);
                for i=1:n+1
                    for j=1:n+1
                        for k=1:m
                            sum=sum+power(xx(k),i+j-2);
                        end
                        a(i, j)=sum;
                        sum=0;
                    end
                end
                sum=0;
                for i=1:n+1
                    for k=1:n
                        sum=sum+power(xx(k),i-1)*yy(k);
                    end
                    b(i)=sum;
                    sum=0;
                end
                c=a\b;
                syms x;
                y=0;
                for i=1:n+1
                    y=y+c(i)*power(x, i-1);
                end
                funDLS=vpa(y,4);
            end
        end

        function TCSI = Cubic1(~, x, y) %三次样条（自然）的算法代码，得到系数矩阵
            n=length(x);
            a=y;
            b=zeros(n-1,1);
            d=zeros(n-1, 1);
            h=diff(x);
            B=zeros(n, 1);
            A=zeros(n, n);
            B(1)=0;
            B(n)=0;

            for i=2:n-1
                B(i)=(3/h(i))*(a(i+1)-a(i))-(3/h(i-1))*(a(i)-a(i-1));
            end

            A(1, 1)=1;
            A(n, n)=1;
            for i=2:n-1
                A(i,i-1)=h(i-1);
                A(i,i)=2*(h(i-1)+h(i));
                A(i,i+1)=h(i);
            end

            c=A\B;
            for i=1:n-1
                b(i)=(a(i+1)-a(i))/h(i)-h(i)*(c(i+1)+2*c(i))/3;
                d(i)=(c(i+1)-c(i))/(3*h(i));
            end

            aa=zeros(n-1, 1);
            cc=zeros(n-1, 1);
            for i=1:n-1
                aa(i)=a(i);
                cc(i)=c(i);
            end

            TCSI=[d cc b aa];
        end

        function TCSI = Cubic2(~, x, y, L, R) %三系样条（限制）的算法，得到系数矩阵
            n=length(x);
            a=y;
            b=zeros(n-1,1);
            d=zeros(n-1, 1);
            h=diff(x);
            B=zeros(n, 1);
            A=zeros(n, n);
            B(1)=3*(a(2)-a(1))/h(1)-3*L;
            B(n)=3*R-3*(a(n)-a(n-1))/h(n-1);

            for i=2:n-1
                B(i)=(3/h(i))*(a(i+1)-a(i))-(3/h(i-1))*(a(i)-a(i-1));
            end

            A(1, 1)=2*h(1);
            A(1, 2)=h(1);
            A(n, n-1)=h(n-1);
            A(n, n)=2*h(n-1);
            for i=2:n-1
                A(i,i-1)=h(i-1);
                A(i,i)=2*(h(i-1)+h(i));
                A(i,i+1)=h(i);
            end

            c=A\B;
            for i=1:n-1
                b(i)=(a(i+1)-a(i))/h(i)-h(i)*(c(i+1)+2*c(i))/3;
                d(i)=(c(i+1)-c(i))/(3*h(i));
            end

            aa=zeros(n-1, 1);
            cc=zeros(n-1, 1);
            for i=1:n-1
                aa(i)=a(i);
                cc(i)=c(i);
            end

            TCSI=[d cc b aa];
        end
        
        function [a, b] = swap(~,x,y) %进行数据交换
            a=y;
            b=x;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadfromExcelButton_4
        function ReadfromExcelButton_4Pushed(app, event)
            app.point=readtable("xydata.xlsx","Sheet",1); %文件读取
            app.xdata=table2array(app.point(:,"x"));
            app.ydata=table2array(app.point(:,"y"));
            
            x_len=length(app.xdata); %对输入数据进行排序
            for i=1:x_len-1
                for j=1: x_len-i
                    if app.xdata(j)>app.xdata(j+1)
                        [app.xdata(j), app.xdata(j+1)]=swap(app, app.xdata(j), app.xdata(j+1));
                        [app.ydata(j), app.ydata(j+1)]=swap(app, app.xdata(j), app.ydata(j+1));
                    end
                end
            end

            app.UITable.Data=[app.xdata app.ydata]; %显示在Table中
        end

        % Button pushed function: ReadfromExcelButton_2
        function ReadfromExcelButton_2Pushed(app, event)
            app.point=readtable("xydata.xlsx","Sheet",1);
            app.xdata=table2array(app.point(:,"x"));
            app.ydata=table2array(app.point(:,"y"));
            
            %对输入数据进行排序
            x_len=length(app.xdata);
            for i=1:x_len-1
                for j=1: x_len-i
                    if app.xdata(j)>app.xdata(j+1)
                        [app.xdata(j), app.xdata(j+1)]=swap(app, app.xdata(j), app.xdata(j+1));
                        [app.ydata(j), app.ydata(j+1)]=swap(app, app.xdata(j), app.ydata(j+1));
                    end
                end
            end

            app.UITable_2.Data=[app.xdata app.ydata];
        end

        % Button pushed function: ReadfromExcelButton_3
        function ReadfromExcelButton_3Pushed(app, event)
            app.point=readtable("xydata.xlsx","Sheet",1);
            app.xdata=table2array(app.point(:,"x"));
            app.ydata=table2array(app.point(:,"y"));

            x_len=length(app.xdata);
            for i=1:x_len-1
                for j=1: x_len-i
                    if app.xdata(j)>app.xdata(j+1)
                        [app.xdata(j), app.xdata(j+1)]=swap(app, app.xdata(j), app.xdata(j+1));
                        [app.ydata(j), app.ydata(j+1)]=swap(app, app.xdata(j), app.ydata(j+1));
                    end
                end
            end

            app.UITable_3.Data=[app.xdata app.ydata];
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            funL=Lagrange(app,app.xdata, app.ydata);
            scatter(app.UIAxes, app.xdata, app.ydata); %散点图
            fplot(app.UIAxes, funL, [app.xdata(1) app.xdata(end)]); %拟合图
            F=vectorize(funL); %符号表达式变为向量表达式
            app.ApproxiationFunctionEditField.Value=F; %拟合结果显示
        end

        % Button pushed function: PlotButton_5
        function PlotButton_5Pushed(app, event)
            n=app.OrderofFuntionEditField.Value; %读取拟合函数的次数
            funDLS=Squares(app, app.xdata, app.ydata, n);
            scatter(app.UIAxes3, app.xdata, app.ydata);
            fplot(app.UIAxes3, funDLS, [app.xdata(1) app.xdata(end)]);
            F=vectorize(funDLS);
            app.ApproxiationFunctionEditField_2.Value=F;
        end

        % Button pushed function: PlotButton_4
        function PlotButton_4Pushed(app, event)
            L=app.LeftderivativeEditField.Value;
            R=app.RightderivativeEditField.Value;
            
            %判断样条类型
            if L==0 && R==0
                TCSI=Cubic1(app, app.xdata, app.ydata);
                app.UITable2.Data=TCSI;
            else
                TCSI=Cubic2(app, app.xdata, app.ydata, L, R);
                app.UITable2.Data=TCSI;
            end

            %绘图
            scatter(app.UIAxes2, app.xdata, app.ydata); 
            n=length(app.xdata);
            syms xx;
            for i=1:n-1
                coe=TCSI(i,:);
                yy=coe(1)*(xx-app.xdata(i))^3+coe(2)*(xx-app.xdata(i))^2+coe(3)*(xx-app.xdata(i))+coe(4);
                fplot(app.UIAxes2, yy, [app.xdata(i) app.xdata(i+1)]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LagrangeInterpolatingPolynomialsTab = uitab(app.TabGroup);
            app.LagrangeInterpolatingPolynomialsTab.Title = 'Lagrange Interpolating Polynomials';
            app.UIAxes = uiaxes(app.LagrangeInterpolatingPolynomialsTab);
            title(app.UIAxes, 'Lagrange Interpolation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.LagrangeInterpolatingPolynomialsTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UITable = uitable(app.LagrangeInterpolatingPolynomialsTab);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.ReadfromExcelButton_4 = uibutton(app.LagrangeInterpolatingPolynomialsTab, 'push');
            app.ReadfromExcelButton_4.ButtonPushedFcn = createCallbackFcn(app, @ReadfromExcelButton_4Pushed, true);
            app.ReadfromExcelButton_4.Text = 'Read from Excel';
            app.ApproxiationFunctionEditFieldLabel = uilabel(app.LagrangeInterpolatingPolynomialsTab);
            app.ApproxiationFunctionEditFieldLabel.Text = 'Approxiation Function';
            app.ApproxiationFunctionEditField = uieditfield(app.LagrangeInterpolatingPolynomialsTab, 'text');
            app.CubicSplineInterpolantTab = uitab(app.TabGroup);
            app.CubicSplineInterpolantTab.Title = 'Cubic Spline Interpolant';
            app.UIAxes2 = uiaxes(app.CubicSplineInterpolantTab);
            title(app.UIAxes2, 'Cubic Spline Interpolant')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.PlotButton_4 = uibutton(app.CubicSplineInterpolantTab, 'push');
            app.PlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_4Pushed, true);
            app.PlotButton_4.Text = 'Plot';
            app.UITable_2 = uitable(app.CubicSplineInterpolantTab);
            app.UITable_2.ColumnName = {'X'; 'Y'};
            app.UITable_2.RowName = {};
            app.ReadfromExcelButton_2 = uibutton(app.CubicSplineInterpolantTab, 'push');
            app.ReadfromExcelButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReadfromExcelButton_2Pushed, true);
            app.ReadfromExcelButton_2.Text = 'Read from Excel';
            app.UITable2 = uitable(app.CubicSplineInterpolantTab);
            app.UITable2.ColumnName = {'x^3'; 'x^2'; 'x'; 'c'};
            app.UITable2.RowName = {};
            app.LeftderivativeEditFieldLabel = uilabel(app.CubicSplineInterpolantTab);
            app.LeftderivativeEditFieldLabel.Text = 'Left derivative';
            app.LeftderivativeEditField = uieditfield(app.CubicSplineInterpolantTab, 'numeric');
            app.RightderivativeEditFieldLabel = uilabel(app.CubicSplineInterpolantTab);
            app.RightderivativeEditFieldLabel.Text = 'Right derivative';
            app.RightderivativeEditField = uieditfield(app.CubicSplineInterpolantTab, 'numeric');
            app.DiscreteLeastSquaresApproximationTab = uitab(app.TabGroup);
            app.DiscreteLeastSquaresApproximationTab.Title = 'Discrete Least Squares Approximation';
            app.UIAxes3 = uiaxes(app.DiscreteLeastSquaresApproximationTab);
            title(app.UIAxes3, 'Discrete Least Squares Approximation')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.PlotButton_5 = uibutton(app.DiscreteLeastSquaresApproximationTab, 'push');
            app.PlotButton_5.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_5Pushed, true);
            app.PlotButton_5.Text = 'Plot';
            app.UITable_3 = uitable(app.DiscreteLeastSquaresApproximationTab);
            app.UITable_3.ColumnName = {'X'; 'Y'};
            app.UITable_3.RowName = {};
            app.ReadfromExcelButton_3 = uibutton(app.DiscreteLeastSquaresApproximationTab, 'push');
            app.ReadfromExcelButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReadfromExcelButton_3Pushed, true);
            app.ReadfromExcelButton_3.Text = 'Read from Excel';
            app.OrderofFuntionEditFieldLabel = uilabel(app.DiscreteLeastSquaresApproximationTab);
            app.OrderofFuntionEditFieldLabel.Text = 'Order of Funtion';
            app.OrderofFuntionEditField = uieditfield(app.DiscreteLeastSquaresApproximationTab, 'numeric');
            app.ApproxiationFunctionEditField_2Label = uilabel(app.DiscreteLeastSquaresApproximationTab);
            app.ApproxiationFunctionEditField_2Label.Text = 'Approxiation Function';
            app.ApproxiationFunctionEditField_2 = uieditfield(app.DiscreteLeastSquaresApproximationTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Approximation_Function

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end